classdef SWRappLiv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        runLamp                 matlab.ui.control.Lamp
        fileField               matlab.ui.control.EditField
        FilenameEditFieldLabel  matlab.ui.control.Label
        saveButton              matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        RunButton               matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        dataTable               matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
   properties (Access = private)
    SerialObj
    dispTab
end
    
    methods (Access = private)
        

    function readData(app, src)

    line = readline(src);

    values = str2double(split(strtrim(line), ","));

    if numel(values) == 7 && all(~isnan(values))

        app.dispTab.Vals = values;

        app.dataTable.Data = app.dispTab;

    end
    end
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
  

    Measurements = {'VSWR'; ...
                    'Reflection Coefficient'; ...
                    'Forward Power'; ...
                    'Reverse Power';...
                    'Foward Voltage';...
                    'Reverse Voltage';...
                    'Returnloss';...
                    };

    Vals = [0;0;0;0;0;0;0];

    app.dispTab = table(Measurements,Vals);

    app.dataTable.Data = app.dispTab;

    app.StopButton.Enable = "off";
    app.saveButton.Enable = "off";

    app.runLamp.Color = [0 0 0];

    app.SerialObj = serialport("COM13",9600);

    configureTerminator(app.SerialObj,"CR/LF");

    flush(app.SerialObj);

    configureCallback(app.SerialObj,"off");


        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.StopButton.Enable = "on";
            app.runLamp.Color = [0 1 0];
            app.RunButton.Enable = "off";
            app.saveButton.Enable = "off";

            %enter a loop, broken when stop is pushed of reading uart
            %values and repopulating every 
            

             configureCallback(app.SerialObj, ...
        "terminator", ...
        @(src,~) readData(app, src));

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
   

    configureCallback(app.SerialObj,"off");

    app.RunButton.Enable = "on";

    app.StopButton.Enable = "off";
    app.saveButton.Enable = "on";
    app.runLamp.Color = [1 0 0];


        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            % here save the table into the filename entered
            fileName = app.fileField.Value +".csv";
            writetable(app.dataTable.Data, fileName);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.saveButton = uibutton(app.LeftPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Save to csv';
            app.FilenameEditFieldLabel = uilabel(app.LeftPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.fileField = uieditfield(app.LeftPanel, 'text');
            app.runLamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.dataTable = uitable(app.RightPanel);
            app.dataTable.ColumnName = {'Measurement'; 'Value'};
            app.dataTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SWRappLiv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end